classdef pokemonApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CorrBetweenStatsTab          matlab.ui.container.Tab
        Switch                       matlab.ui.control.Switch
        SwitchLabel                  matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        YStatDropDown                matlab.ui.control.DropDown
        YStatDropDownLabel           matlab.ui.control.Label
        XStatDropDown                matlab.ui.control.DropDown
        XStatLabel                   matlab.ui.control.Label
        PokemonTypeDropDown          matlab.ui.control.DropDown
        PokemonTypeDropDownLabel     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        TypeDistrGensTab             matlab.ui.container.Tab
        PercentageCheckBox           matlab.ui.control.CheckBox
        GridLinesCheckBox            matlab.ui.control.CheckBox
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        PokedexImageBonusTab         matlab.ui.container.Tab
        PokemonIDNumberEditField     matlab.ui.control.NumericEditField
        PokedexNumberEditFieldLabel  matlab.ui.control.Label
        PokemonImage                 matlab.ui.control.Image
        LookupTableBonus             matlab.ui.container.Tab
        EditField3                   matlab.ui.control.EditField
        EditField3Label              matlab.ui.control.Label
        EditField2                   matlab.ui.control.EditField
        EditField2Label              matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
    end

    
    properties (Access = public)
        pokeTable % Table of all the Pokemon
        pokeTypes % All the Types of Pokemon
        pokeColorDict % Color Dictionary
        
        pokeStats = ["Total","HP","Attack","Defense","Sp_Atk","Sp_Def","Speed"]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set initial conditions
            app.pokeTable = readAndFilterTable();
            app.pokeTypes = getTypesList(app.pokeTable);
            app.pokeColorDict = matchTypesAndColors(app.pokeTable);
                
            % Update Tab 1 DropDown
            app.PokemonTypeDropDown.Items = app.pokeTypes;
            app.PokemonTypeDropDown.Value = "Electric";

            app.XStatDropDown.Items = app.pokeStats;
            app.YStatDropDown.Items = app.pokeStats;
            
            app.XStatDropDown.Value = app.pokeStats(1);
            app.YStatDropDown.Value = app.pokeStats(1);
            
            % Upate Tab 1 Plot
            pokeCorrFunction(app)
            % Update Tab 2 Plot
            pokeDistFunc(app,app.Slider.Value,app.PercentageCheckBox.Value)
            % Update Tab 4 Image
            app.PokemonImage.ImageSource = getPokemonPicture(app.PokemonIDNumberEditField.Value);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value);
            pokeDistFunc(app,app.Slider.Value,app.PercentageCheckBox.Value)
            
        end

        % Value changed function: GridLinesCheckBox
        function GridLinesCheckBoxValueChanged(app, event)
            pokeDistFunc(app,app.Slider.Value,app.PercentageCheckBox.Value)
            
        end

        % Value changed function: PercentageCheckBox
        function PercentageCheckBoxValueChanged(app, event)
            pokeDistFunc(app,app.Slider.Value,app.PercentageCheckBox.Value)
        end

        % Value changed function: PokemonTypeDropDown
        function PokemonTypeDropDownValueChanged(app, event)
            value = app.PokemonTypeDropDown.Value;
            pokeCorrFunction(app)
        end

        % Value changed function: XStatDropDown
        function XStatDropDownValueChanged(app, event)
            value = app.XStatDropDown.Value;
            pokeCorrFunction(app)
        end

        % Value changed function: YStatDropDown
        function YStatDropDownValueChanged(app, event)
            value = app.YStatDropDown.Value;
            pokeCorrFunction(app)
        end

        % Value changed function: PokemonIDNumberEditField
        function PokemonIDNumberEditFieldValueChanged(app, event)
            app.PokemonImage.ImageSource = getPokemonPicture(app.PokemonIDNumberEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CorrBetweenStatsTab = uitab(app.TabGroup);
            app.CorrBetweenStatsTab.Title = 'Corr. Between Stats';
            app.UIAxes = uiaxes(app.CorrBetweenStatsTab);
            title(app.UIAxes, 'Correlation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PokemonTypeDropDownLabel = uilabel(app.CorrBetweenStatsTab);
            app.PokemonTypeDropDownLabel.Text = 'Pokemon Type';
            app.PokemonTypeDropDown = uidropdown(app.CorrBetweenStatsTab);
            app.PokemonTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PokemonTypeDropDownValueChanged, true);
            app.XStatLabel = uilabel(app.CorrBetweenStatsTab);
            app.XStatLabel.Text = 'X-Stat';
            app.XStatDropDown = uidropdown(app.CorrBetweenStatsTab);
            app.XStatDropDown.ValueChangedFcn = createCallbackFcn(app, @XStatDropDownValueChanged, true);
            app.YStatDropDownLabel = uilabel(app.CorrBetweenStatsTab);
            app.YStatDropDownLabel.Text = 'Y-Stat';
            app.YStatDropDown = uidropdown(app.CorrBetweenStatsTab);
            app.YStatDropDown.ValueChangedFcn = createCallbackFcn(app, @YStatDropDownValueChanged, true);
            app.Image = uiimage(app.CorrBetweenStatsTab);
            app.Image2 = uiimage(app.CorrBetweenStatsTab);
            app.SwitchLabel = uilabel(app.CorrBetweenStatsTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.CorrBetweenStatsTab, 'slider');
            app.Switch.Items = {'Lowest', 'Highest'};
            app.Switch.Value = 'Lowest';
            app.TypeDistrGensTab = uitab(app.TabGroup);
            app.TypeDistrGensTab.Title = 'Type Distr. Gens';
            app.UIAxes2 = uiaxes(app.TypeDistrGensTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SliderLabel = uilabel(app.TypeDistrGensTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.TypeDistrGensTab);
            app.Slider.Limits = [1 6];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = 0;
            app.Slider.Value = 1;
            app.GridLinesCheckBox = uicheckbox(app.TypeDistrGensTab);
            app.GridLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridLinesCheckBoxValueChanged, true);
            app.GridLinesCheckBox.Text = 'Grid Lines';
            app.PercentageCheckBox = uicheckbox(app.TypeDistrGensTab);
            app.PercentageCheckBox.ValueChangedFcn = createCallbackFcn(app, @PercentageCheckBoxValueChanged, true);
            app.PercentageCheckBox.Text = 'Percentage';
            app.PokedexImageBonusTab = uitab(app.TabGroup);
            app.PokedexImageBonusTab.Title = 'Pokedex Image (Bonus)';
            app.PokemonImage = uiimage(app.PokedexImageBonusTab);
            app.PokedexNumberEditFieldLabel = uilabel(app.PokedexImageBonusTab);
            app.PokedexNumberEditFieldLabel.Text = 'Pokedex Number';
            app.PokemonIDNumberEditField = uieditfield(app.PokedexImageBonusTab, 'numeric');
            app.PokemonIDNumberEditField.Limits = [1 1025];
            app.PokemonIDNumberEditField.RoundFractionalValues = 'on';
            app.PokemonIDNumberEditField.ValueChangedFcn = createCallbackFcn(app, @PokemonIDNumberEditFieldValueChanged, true);
            app.PokemonIDNumberEditField.Placeholder = '1';
            app.PokemonIDNumberEditField.Value = 1;
            app.LookupTableBonus = uitab(app.TabGroup);
            app.LookupTableBonus.Title = 'Lookup Table (Bonus)';
            app.Image3 = uiimage(app.LookupTableBonus);
            app.TextAreaLabel = uilabel(app.LookupTableBonus);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.LookupTableBonus);
            app.EditFieldLabel = uilabel(app.LookupTableBonus);
            app.EditFieldLabel.Text = 'Edit Field`';
            app.EditField = uieditfield(app.LookupTableBonus, 'text');
            app.EditField2Label = uilabel(app.LookupTableBonus);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.LookupTableBonus, 'text');
            app.EditField3Label = uilabel(app.LookupTableBonus);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.LookupTableBonus, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokemonApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end